classdef ROS_Control_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROS_CONTROL_APP           matlab.ui.Figure
        connect2ros               matlab.ui.control.Button
        run_model                 matlab.ui.control.Button
        forward                   matlab.ui.control.Button
        backward                  matlab.ui.control.Button
        left                      matlab.ui.control.Button
        right                     matlab.ui.control.Button
        Label                     matlab.ui.control.Label
        lamp_connect2ros          matlab.ui.control.Lamp
        Label_2                   matlab.ui.control.Label
        lamp_run_model            matlab.ui.control.Lamp
        ModelNameEditFieldLabel   matlab.ui.control.Label
        ModelNameEditField        matlab.ui.control.EditField
        ROSIPEditFieldLabel       matlab.ui.control.Label
        ROSIPEditField            matlab.ui.control.EditField
        SimTargetEditFieldLabel   matlab.ui.control.Label
        SimTargetEditField        matlab.ui.control.EditField
        RobotPositionUIAxes       matlab.ui.control.UIAxes
        LidarViewUIAxes           matlab.ui.control.UIAxes
        ManualSwitchLabel         matlab.ui.control.Label
        ManualSwitch              matlab.ui.control.Switch
        RotationradsecKnobLabel   matlab.ui.control.Label
        RotationradsecKnob        matlab.ui.control.Knob
        AvoidanceActiveLampLabel  matlab.ui.control.Label
        AvoidanceActiveLamp       matlab.ui.control.Lamp
        EditFieldSpeed            matlab.ui.control.NumericEditField
        EditFieldRotation         matlab.ui.control.NumericEditField
        Stop                      matlab.ui.control.Button
        ResetSpeedButton          matlab.ui.control.Button
        ResetOdometryButton       matlab.ui.control.Button
        PublishWaypointsButton    matlab.ui.control.Button
        RestartWaypointsButton    matlab.ui.control.Button
        WaypointsLabel            matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        speedSlider               matlab.ui.control.Slider
        SpeedmsLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)      
        velmsg 
        robot 
        teleopTopicName 
        Waypoints = [0 0;2 0;4 -3;-4 -4];
        pose 
        laserData
        handles
        scan % 
        plotobj % 
        laser % 激光
        FigureHandle = []; % Handle tp the object figure
        AxesHandle = []; % 坐标
        Goal = []; % 目标：路径点
        PoseHandle = []; % 位姿
        ArrowHandle = []; % 方向
        PoseHistory = []; % 历史位姿
        DataHistory = []; % 历史数据
        MaxPoseHistorySize = 10; % 最大位姿历史大小
        MaxDataHistorySize = 5; % 最大数据历史大小
    end
    
    methods (Access = private)    
        function  plotData(app,pose,data)
            if ~ishandle(app.FigureHandle)
                %Check that figure hasn't been closed
                error('Figure close request:Exiting')
            end
            %Plot pose
            plotPose(app,pose);
            
            th = pose(3)-pi/2;
            % Compute the world-frame location of laser points
            dataWorld = data*[cos(th) sin(th);-sin(th) cos(th)] ...
                + repmat(pose(1:2),[numel(data(:,1)),1]);
            
            % Plot the transformed laser data on the world map
            % Also keep a history of previous laser data handles (fixed-size FIFO)
            laserDataHandle = plot(app.RobotPositionUIAxes,dataWorld(:,1), dataWorld(:,2), '*','MarkerSize',2,'Color','b');
            %hold(app.RobotPositionUIAxes);
            addNewLaserDataHandle(app,laserDataHandle);
        end
        
        function plotPose(app,pose)
            %PLOTPOSE - Plots the current Turtlebot pose
            if ~ishandle(app.FigureHandle)
                % Check that figure hasn't been closed
                error('Figure close request: Exiting');
            end
            % Delete current pose objects from plot
            delete(app.PoseHandle);
            delete(app.ArrowHandle);
            
            % plot current pose and direction
            app.PoseHandle = plot(app.RobotPositionUIAxes, pose(1),pose(2),'o','MarkerSize',5,'Color','g');
            %hold(app.RobotPositionUIAxes);
            app.ArrowHandle = plot(app.RobotPositionUIAxes,[pose(1), pose(1) + 0.5*cos(pose(3))],...
                [pose(2), pose(2) + 0.5*sin(pose(3))],...
                '*','MarkerSize',1,'Color','r','LineStyle','-');
            
            %hold(app.RobotPositionUIAxes);
            % Keep a history of prevoius robot positions (fixed-size FIFO)
            poseHistoryHandle = plot(app.RobotPositionUIAxes,pose(1),pose(2),'*','Color','c','MarkerSize',2);
            %hold(app.RobotPositionUIAxes);
            addNewPoseHandle(app,poseHistoryHandle);     
        end
        
        function addNewPoseHandle(app,poseHandle)
            %addNewPoseHandle Store a new pose graphics handle
            
            app.PoseHistory = [app.PoseHistory; poseHandle];
            if length(app.PoseHistory) > app.MaxPoseHistorySize
                % If we reached the maximum size of the array, delete the
                % oldest graphics handle
                delete(app.PoseHistory(1));
                app.PoseHistory(1) = [];
            end
        end
        
        function addNewLaserDataHandle(app,laserDataHandle)
            %addNewLaserDataHandle Store a new laser data graphics handle
            
            app.DataHistory = [app.DataHistory; laserDataHandle];
            if length(app.DataHistory) > app.MaxDataHistorySize
                % If we reached the maximum size of the array,raph delete the
                % oldest graphics handle
                delete(app.DataHistory(1));
                app.DataHistory(1) = [];
            end
        end
        
        function closeFigure(app,~,~)
            %CLOSEFIGURE-Callback function that deletes the figure
            % handle
            delete(app.FigureHandle);
            
        end
        
        function app = ExampleHelperTurtleBotVisualizer(app,ranges)
              
            %ExampleHelperTurtleBotVisualizer - Constructor sets all the figure properties
            %app.FigureHandle = figure('Name','Robot Position','CloseRequestFcn',@app.closeFigure);
            app.RobotPositionUIAxes = axes('Parent','XGrid','on','YGrid','on','XLimMode','manual','YLimMode','manual');
            axis(app.RobotPositionUIAxes,ranges);
            hold(app.RobotPositionUIAxes);
                
        end
        
        function plotGoal(app,goal)
         
            %PLOTGOAL - Plots the goal point on the graph
            
            if ~ishandle(app.FigureHandle)
                % Check that figure hasn't been closed
                error('Figure close request: Exiting');
            end
            plot(app.RobotPositionUIAxes,goal(:,1),goal(:,2),'O','Color','m','MarkerSize',6);
            hold(app.RobotPositionUIAxes);
            %plot(app.RobotPositionUIAxes,goal(1),goal(2),'d','Color','m','MarkerSize',6);
            app.Goal = [app.Goal;goal];
        end
        
        function Avoiding(app)
            %正在执行避障处理：点亮避障指示  向量直方图
        end
    end
    
    methods (Access = public)
        function laserData = readLaserData(app,laserMsg)
            %readPose Extract the laser data in Cartesian coordinates
            laserData = readCartesian(laserMsg) * [0 1; -1 0];
        end
        
        function pose = readPose(app,odomMsg)
            %readPose Extract the robot odometry reading as [x y theta] vector
            % Extract the x, y, and theta coordinates
            poseMsg = odomMsg.Pose.Pose;
            xpos = poseMsg.Position.X;
            ypos = poseMsg.Position.Y;
            quat = poseMsg.Orientation;
            angles = quat2eul([quat.W quat.X quat.Y quat.Z]);
            theta = angles(1);
            pose = [xpos, ypos, theta];
        end
        
        function exampleHelperTurtleBotKeyboardControl(app,handles)
            %exampleHelperTurtleBotKeyboardControl - Allows for user control of Turtlebot through keyboard commands
            %   exampleHelperTurtleBotKeyboardControl(HANDLES) takes a set of subscriber
            %   and publisher handles as input and publishes velocity commands according
            %   to user keyboard input. The turtlebot location is plotted.
            %
            %   See also TurtleBotTeleoperationExample
            %   Copyright 2014-2015 The MathWorks, Inc.
            %ExampleHelperTurtleBotVisualizer(app,[-10,10,-10,10]);
           
            app.pose = readPose(app,app.handles.odomSub.LatestMessage);
            app.laserData = readLaserData(app,app.handles.laserSub.LatestMessage);            
            r = robotics.Rate(10);
            reset(r);
            while app.lamp_connect2ros.Color ~= 'r' 
                laserMsg = receive(app.handles.laserSub,3);
                odomMsg = app.handles.odomSub.LatestMessage;
                app.laserData = readLaserData(app,laserMsg);
                app.pose = readPose(app,odomMsg);
                plotData(app,app.pose,app.laserData); 
                plot(app.LidarViewUIAxes,app.laserData(:,1),app.laserData(:,2),'*','MarkerSize',2,'Color','m');
                waitfor(r);
            end
        end       
     end
        

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load the data.
            %load G:\ROS_AGV\Waypoints\Waypoints.mat
            %load G:\Waypoints\Waypoints.mat
%             plot(app.RobotPositionUIAxes,1,1,'o','MarkerSize',3,'Color','k');
%             hold(app.RobotPositionUIAxes);
            % Store the data in a table and display it in one of the App's tabs
            
            %app.Waypoints = uitable(X,Y);
            %disp(app.Waypoints(2,1))
            %Waypoints 路径点
            plotGoal(app,app.Waypoints); 
            app.UITable.Data = app.Waypoints;
        end

        % Button pushed function: connect2ros
        function connect2rosButtonPushed(app, event)

                rosip = app.ROSIPEditField.Value;
                %rosMasterUri = 'http://192.168.157.132:11311';
                rosip_http = ['http://',rosip];
                rosMasterUri = [rosip_http,':11311'];
                app.teleopTopicName = '/cmd_vel';
                if app.lamp_connect2ros.Color == [1 0 0]
                    %setenv('ROS_IP','192.168.8.231');
                    %setenv('ROS_IP','192.168.1.102');
                    setenv('ROS_IP','192.168.40.1');
                    setenv('ROS_MASTER_URI',rosMasterUri);
                    rosinit
                    app.robot = rospublisher(app.teleopTopicName,'geometry_msgs/Twist');
                    app.velmsg = rosmessage(app.robot);
                    app.handles.odomSub = rossubscriber('/odom', 'BufferSize', 25);
                    receive(app.handles.odomSub,3);
                    app.handles.laserSub = rossubscriber('/scan', 'BufferSize', 5);
                    receive(app.handles.laserSub,3);                    
                    app.pose = readPose(app,app.handles.odomSub.LatestMessage);
                    app.laserData = readLaserData(app,app.handles.laserSub.LatestMessage);
                    app.laser = rossubscriber('/scan');
                    app.lamp_connect2ros.Color = [0 1 0];
                    exampleHelperTurtleBotKeyboardControl(app,app.handles);
                else
                    rosshutdown;
                    app.lamp_connect2ros.Color = [1 0 0];
                end
        end

        % Button pushed function: run_model
        function run_modelButtonPushed(app, event)
            %运行slam:实时定位和构图 
            run ./LIDAR_SLAM/PathFollow.m
            %run ./AGV_Arithmetic_Pro/'07-25 ErrCorr1'/'07-25 ErrCorr'/LM_Localize.m
            if app.lamp_run_model.Color == [1 0 0]
                app.lamp_run_model.Color = [0 1 0];
            else
                app.lamp_run_model.Color = [1 0 0];
            end
            
        end

        % Button pushed function: forward
        function forwardButtonPushed(app, event)
                %app.forward.Enable = 0
                app.velmsg.Angular.Z = 0;
                app.velmsg.Linear.X = 0.5;
                send(app.robot,app.velmsg);
                rospublisher(app.teleopTopicName, 'IsLatching', true);
        end

        % Button pushed function: backward
        function backwardButtonPushed(app, event)

                %global backwardVelocity              
                app.velmsg.Angular.Z = 0;
                app.velmsg.Linear.X = -0.5;
                send(app.robot,app.velmsg);
                rospublisher(app.teleopTopicName, 'IsLatching', true);
        end

        % Button pushed function: left
        function leftButtonPushed(app, event)

                %global leftVelocity               
                app.velmsg.Angular.Z = 0.5;
                app.velmsg.Linear.X = 0;
                send(app.robot,app.velmsg);
                rospublisher(app.teleopTopicName, 'IsLatching', true);
        end

        % Button pushed function: right
        function rightButtonPushed(app, event)

                %global rightVelocity               
                app.velmsg.Angular.Z = -0.5;
                app.velmsg.Linear.X = 0;
                send(app.robot,app.velmsg);
                rospublisher(app.teleopTopicName, 'IsLatching', true);
        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
                %if app.ManualSwitch.Enable == 'true'
                app.velmsg.Angular.Z = 0;
                app.velmsg.Linear.X = 0;
                send(app.robot,app.velmsg);
                rospublisher(app.teleopTopicName, 'IsLatching', true);
        end

        % Value changed function: ManualSwitch
        function ManualSwitchValueChanged(app, event)

           if app.ManualSwitch.Value == "true"
                app.forward.Enable = 'on';
                app.backward.Enable = 'on';
                app.left.Enable = 'on';
                app.right.Enable = 'on';
                app.Stop.Enable = 'on';
                app.speedSlider.Enable = 'on';
                app.RotationradsecKnob.Enable = 'on';
                app.EditFieldSpeed.Enable = 'on';
                app.EditFieldRotation.Enable = 'on';
            elseif app.ManualSwitch.Value == "false"
                app.forward.Enable = 'off';
                app.backward.Enable = 'off';
                app.left.Enable = 'off';
                app.right.Enable = 'off';
                app.Stop.Enable = 'off';
                app.speedSlider.Enable = 'off';
                app.RotationradsecKnob.Enable = 'off';  
                app.EditFieldSpeed.Enable = 'off';
                app.EditFieldRotation.Enable = 'off';                
            end
        end

        % Button pushed function: ResetSpeedButton
        function ResetSpeedButtonPushed(app, event)
            %重置robot速度
            
        end

        % Button pushed function: ResetOdometryButton
        function ResetOdometryButtonPushed(app, event)
            %重置robot里程计
            client = rossvcclient('/gazebo/reset_world');
            call(client);           
        end

        % Button pushed function: PublishWaypointsButton
        function PublishWaypointsButtonPushed(app, event)
            %发布路径点  plot路径点至全局坐标
            %delete old points,plot new points
            hold(app.RobotPositionUIAxes,'off');
            plotGoal(app,app.Waypoints); 
        end

        % Button pushed function: RestartWaypointsButton
        function RestartWaypointsButtonPushed(app, event)
            %重新开始路径点巡线 robot重新从第一个路径点开始循环行进
        end

        % Value changed function: EditFieldSpeed
        function EditFieldSpeedValueChanged(app, event)
            app.velmsg.Angular.Z = 0;
            app.velmsg.Linear.X = app.EditFieldSpeed.Value;
            r = robotics.Rate(10);
            reset(r);
            %while 1
                send(app.robot,app.velmsg);
                rospublisher(app.teleopTopicName, 'IsLatching', true);
               % waitfor(r);
           %end
        end

        % Value changed function: EditFieldRotation
        function EditFieldRotationValueChanged(app, event)
            app.velmsg.Angular.Z = app.EditFieldRotation.Value;
            app.velmsg.Linear.X = 0;
            r = robotics.Rate(10);
            reset(r);
            %while 1
                send(app.robot,app.velmsg);
                rospublisher(app.teleopTopicName, 'IsLatching', true);
            %   waitfor(r);
            %end
        end

        % Value changed function: RotationradsecKnob
        function RotationradsecKnobValueChanged(app, event)
            app.EditFieldRotation.Value = app.RotationradsecKnob.Value;
            app.velmsg.Angular.Z = app.EditFieldRotation.Value;
            app.velmsg.Linear.X = 0;
            send(app.robot,app.velmsg);
            rospublisher(app.teleopTopicName, 'IsLatching', true);           
        end

        % Value changed function: speedSlider
        function speedSliderValueChanged(app, event)
            app.EditFieldSpeed.Value = app.speedSlider.Value;
            app.velmsg.Angular.Z = 0;
            app.velmsg.Linear.X = app.EditFieldSpeed.Value;
            send(app.robot,app.velmsg);
            rospublisher(app.teleopTopicName, 'IsLatching', true);            
        end

        % Callback function
        function SLAMButtonPushed(app, event)
            %开始在未知封闭室内环境构建全局地图
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            app.Waypoints = app.UITable.Data;
            disp(app.Waypoints)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROS_CONTROL_APP = uifigure;
            app.ROS_CONTROL_APP.Name = 'ROS_Control_App';
            app.connect2ros = uibutton(app.ROS_CONTROL_APP, 'push');
            app.connect2ros.ButtonPushedFcn = createCallbackFcn(app, @connect2rosButtonPushed, true);
            app.connect2ros.Text = 'Connect2Ros';
            app.run_model = uibutton(app.ROS_CONTROL_APP, 'push');
            app.run_model.ButtonPushedFcn = createCallbackFcn(app, @run_modelButtonPushed, true);
            app.run_model.Text = 'Run Model';
            app.forward = uibutton(app.ROS_CONTROL_APP, 'push');
            app.forward.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forward.Enable = 'off';
            app.forward.Text = 'forward';
            app.backward = uibutton(app.ROS_CONTROL_APP, 'push');
            app.backward.ButtonPushedFcn = createCallbackFcn(app, @backwardButtonPushed, true);
            app.backward.Enable = 'off';
            app.backward.Text = 'backward';
            app.left = uibutton(app.ROS_CONTROL_APP, 'push');
            app.left.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.left.Enable = 'off';
            app.left.Text = 'left';
            app.right = uibutton(app.ROS_CONTROL_APP, 'push');
            app.right.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.right.Enable = 'off';
            app.right.Text = 'right';
            app.Label = uilabel(app.ROS_CONTROL_APP);
            app.Label.Text = '';
            app.lamp_connect2ros = uilamp(app.ROS_CONTROL_APP);
            app.Label_2 = uilabel(app.ROS_CONTROL_APP);
            app.Label_2.Text = '';
            app.lamp_run_model = uilamp(app.ROS_CONTROL_APP);
            app.ModelNameEditFieldLabel = uilabel(app.ROS_CONTROL_APP);
            app.ModelNameEditFieldLabel.Text = 'Model Name';
            app.ModelNameEditField = uieditfield(app.ROS_CONTROL_APP, 'text');
            app.ModelNameEditField.Value = 'Lidar_Agv';
            app.ROSIPEditFieldLabel = uilabel(app.ROS_CONTROL_APP);
            app.ROSIPEditFieldLabel.Text = 'ROS IP';
            app.ROSIPEditField = uieditfield(app.ROS_CONTROL_APP, 'text');
            app.ROSIPEditField.Value = '192.168.40.128';
            app.SimTargetEditFieldLabel = uilabel(app.ROS_CONTROL_APP);
            app.SimTargetEditFieldLabel.Text = 'Sim Target';
            app.SimTargetEditField = uieditfield(app.ROS_CONTROL_APP, 'text');
            app.SimTargetEditField.Value = 'MRobot_Gazebo';
            app.RobotPositionUIAxes = uiaxes(app.ROS_CONTROL_APP);
            title(app.RobotPositionUIAxes, 'Robot Position')
            xlabel(app.RobotPositionUIAxes, 'X Position')
            ylabel(app.RobotPositionUIAxes, 'Y Position ')
            app.LidarViewUIAxes = uiaxes(app.ROS_CONTROL_APP);
            title(app.LidarViewUIAxes, 'Lidar View')
            xlabel(app.LidarViewUIAxes, 'Robot-Y(side)')
            ylabel(app.LidarViewUIAxes, 'Robot-X(front)')
            app.ManualSwitchLabel = uilabel(app.ROS_CONTROL_APP);
            app.ManualSwitchLabel.Text = 'Manual';
            app.ManualSwitch = uiswitch(app.ROS_CONTROL_APP, 'slider');
            app.ManualSwitch.Items = {'false', 'true'};
            app.ManualSwitch.ValueChangedFcn = createCallbackFcn(app, @ManualSwitchValueChanged, true);
            app.ManualSwitch.Value = 'false';
            app.RotationradsecKnobLabel = uilabel(app.ROS_CONTROL_APP);
            app.RotationradsecKnobLabel.Text = 'Rotation(rad/sec)';
            app.RotationradsecKnob = uiknob(app.ROS_CONTROL_APP, 'continuous');
            app.RotationradsecKnob.Limits = [-1 1];
            app.RotationradsecKnob.MajorTicks = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.RotationradsecKnob.ValueChangedFcn = createCallbackFcn(app, @RotationradsecKnobValueChanged, true);
            app.RotationradsecKnob.Enable = 'off';
            app.AvoidanceActiveLampLabel = uilabel(app.ROS_CONTROL_APP);
            app.AvoidanceActiveLampLabel.Text = 'Avoidance Active';
            app.AvoidanceActiveLamp = uilamp(app.ROS_CONTROL_APP);
            app.EditFieldSpeed = uieditfield(app.ROS_CONTROL_APP, 'numeric');
            app.EditFieldSpeed.ValueChangedFcn = createCallbackFcn(app, @EditFieldSpeedValueChanged, true);
            app.EditFieldSpeed.Enable = 'off';
            app.EditFieldRotation = uieditfield(app.ROS_CONTROL_APP, 'numeric');
            app.EditFieldRotation.Limits = [-1 1];
            app.EditFieldRotation.ValueChangedFcn = createCallbackFcn(app, @EditFieldRotationValueChanged, true);
            app.EditFieldRotation.Enable = 'off';
            app.Stop = uibutton(app.ROS_CONTROL_APP, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Enable = 'off';
            app.Stop.Text = 'Stop';
            app.ResetSpeedButton = uibutton(app.ROS_CONTROL_APP, 'push');
            app.ResetSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSpeedButtonPushed, true);
            app.ResetSpeedButton.Text = 'Reset Speed';
            app.ResetOdometryButton = uibutton(app.ROS_CONTROL_APP, 'push');
            app.ResetOdometryButton.ButtonPushedFcn = createCallbackFcn(app, @ResetOdometryButtonPushed, true);
            app.ResetOdometryButton.Text = 'Reset Odometry';
            app.PublishWaypointsButton = uibutton(app.ROS_CONTROL_APP, 'push');
            app.PublishWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @PublishWaypointsButtonPushed, true);
            app.PublishWaypointsButton.Text = 'Publish Waypoints';
            app.RestartWaypointsButton = uibutton(app.ROS_CONTROL_APP, 'push');
            app.RestartWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @RestartWaypointsButtonPushed, true);
            app.RestartWaypointsButton.Text = 'Restart Waypoints';
            app.WaypointsLabel = uilabel(app.ROS_CONTROL_APP);
            app.WaypointsLabel.Text = 'Waypoints';
            app.UITable = uitable(app.ROS_CONTROL_APP);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {'1'; '2'; '3'; '4'};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.speedSlider = uislider(app.ROS_CONTROL_APP);
            app.speedSlider.Limits = [-0.5 0.5];
            app.speedSlider.ValueChangedFcn = createCallbackFcn(app, @speedSliderValueChanged, true);
            app.speedSlider.Enable = 'off';
            app.SpeedmsLabel = uilabel(app.ROS_CONTROL_APP);
            app.SpeedmsLabel.Text = 'Speed(m/s)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ROS_Control_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROS_CONTROL_APP)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROS_CONTROL_APP)
        end
    end
end